classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout2               matlab.ui.container.GridLayout
        GUIPanel                  matlab.ui.container.Panel
        EnckderDataPanel          matlab.ui.container.Panel
        GridLayout6_2             matlab.ui.container.GridLayout
        rightEncoderData          matlab.ui.control.Label
        leftEncoderData           matlab.ui.control.Label
        RightEncoderLabel         matlab.ui.control.Label
        LeftEncoderLabel          matlab.ui.control.Label
        SonarDataPanel            matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        rightSonarData            matlab.ui.control.Label
        leftSonarData             matlab.ui.control.Label
        RightLabel_2              matlab.ui.control.Label
        LeftLabel_2               matlab.ui.control.Label
        LidarDataPanel            matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        rightLidarData            matlab.ui.control.Label
        leftLidarData             matlab.ui.control.Label
        backLidarData             matlab.ui.control.Label
        frontLidarData            matlab.ui.control.Label
        RightLabel                matlab.ui.control.Label
        LeftLabel                 matlab.ui.control.Label
        BackLabel                 matlab.ui.control.Label
        FrontLabel                matlab.ui.control.Label
        MQTTConnectionPanel       matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ConnectStatusLabel        matlab.ui.control.Label
        mqttConnectSwitch         matlab.ui.control.Switch
        ServerPortEditField       matlab.ui.control.EditField
        ServerPortEditFieldLabel  matlab.ui.control.Label
        ServerUrlEditField        matlab.ui.control.EditField
        ServerUrlEditFieldLabel   matlab.ui.control.Label
        LEDStatusPanel            matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        YellowLedLabel            matlab.ui.control.Label
        GreenLedLabel             matlab.ui.control.Label
        RedLedLabel               matlab.ui.control.Label
        YellowLedLamp             matlab.ui.control.Lamp
        GreenLedLamp              matlab.ui.control.Lamp
        RedLedLamp                matlab.ui.control.Lamp
    end


    properties (Access = private)
        walterRobot = "NULL";
        ledOffColor = [0.8 0.8 0.8];
        redLedOnColor = [1 0 0];
        greenLedOnColor = [0 1 0];
        yellowLedOnColor = [1 1 0];

        isPressingKey = false;

    end

    methods (Access = private)

        function lidarDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.lidarDataTopic)
                app.walterRobot.lidarData = str2double(split(message));

                app.frontLidarData.Text = string(app.walterRobot.lidarData(1));
                app.backLidarData.Text = string(app.walterRobot.lidarData(2));
                app.leftLidarData.Text = string(app.walterRobot.lidarData(3));
                app.rightLidarData.Text = string(app.walterRobot.lidarData(4));


            end
        end

        function sonarDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.sonarDataTopic)
                app.walterRobot.sonarData = str2double(split(message));

                app.leftSonarData.Text = string(app.walterRobot.sonarData(1));
                app.rightSonarData.Text = string(app.walterRobot.sonarData(2));

            end
        end

        function encoderDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.encoderDataTopic)
                app.walterRobot.encoderData = str2double(split(message));

                app.leftEncoderData.Text = string(app.walterRobot.encoderData(1));
                app.rightEncoderData.Text = string(app.walterRobot.encoderData(2));

            end
        end

        function ledDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
            if(topic==app.walterRobot.ledDataTopic)
                app.walterRobot.ledData = split(message);

                % Red LED
                if app.walterRobot.ledData(1) == "0"
                    app.RedLedLamp.Color = app.ledOffColor;
                else
                    app.RedLedLamp.Color = app.redLedOnColor;
                end

                % Green LED
                if app.walterRobot.ledData(2) == "0"
                    app.GreenLedLamp.Color = app.ledOffColor;
                else
                    app.GreenLedLamp.Color = app.greenLedOnColor;
                end

                % Yellow LED
                if app.walterRobot.ledData(3) == "0"
                    app.YellowLedLamp.Color = app.ledOffColor;
                else
                    app.YellowLedLamp.Color = app.yellowLedOnColor;
                end

            end
        end

        function matrixDataCallback(app, topic, message)
            disp("Received from " + topic);
            disp("Message: " + message);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: mqttConnectSwitch
        function mqttConnectSwitchValueChanged(app, event)
            value = app.mqttConnectSwitch.Value;

            if (value == "Connect")
                app.walterRobot = WalterRobot();
                disp('Connecting to MQTT Server...');
                app.walterRobot.mqClient = mqttclient(app.ServerUrlEditField.Value, Port=str2num(app.ServerPortEditField.Value));

                % add subscription topics
                app.walterRobot.mqClient.subscribe(app.walterRobot.lidarDataTopic, "Callback", @app.lidarDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.sonarDataTopic, "Callback", @app.sonarDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.encoderDataTopic, "Callback", @app.encoderDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.ledDataTopic, "Callback", @app.ledDataCallback);
                app.walterRobot.mqClient.subscribe(app.walterRobot.matrixDataTopic, "Callback", @app.matrixDataCallback);
            else
                app.walterRobot = "NULL";
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(~app.isPressingKey)
                switch event.Key
                    case 'w'
                        % disp("前进");
                        app.walterRobot.sendForwardControl();
                    case 's'
                        % disp("后退");
                        app.walterRobot.sendBackwardControl();
                    case 'a'
                        % disp("左转");
                        app.walterRobot.sendTurnLeftControl();
                    case 'd'
                        % disp("右转");
                        app.walterRobot.sendTurnRightControl();

                    % otherwise
                    %     disp("其他按键: " + event.Key);
                end

                app.isPressingKey = true;
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)

            % 判断具体按键
            switch event.Key
                case 'w'
                    % disp("停止前进");
                    app.walterRobot.sendMoveStopControl();
                case 's'
                    % disp("停止后退");
                    app.walterRobot.sendMoveStopControl();
                case 'a'
                    % disp("停止左转");
                    app.walterRobot.sendMoveStopControl();
                case 'd'
                    % disp("停止右转");
                    app.walterRobot.sendMoveStopControl();
            end
            app.isPressingKey = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.GUIPanel = uipanel(app.GridLayout2);
            app.GUIPanel.Title = 'GUI Panel';
            app.LEDStatusPanel = uipanel(app.GUIPanel);
            app.LEDStatusPanel.Title = 'LED Status';
            app.GridLayout = uigridlayout(app.LEDStatusPanel);
            app.RedLedLamp = uilamp(app.GridLayout);
            app.GreenLedLamp = uilamp(app.GridLayout);
            app.YellowLedLamp = uilamp(app.GridLayout);
            app.RedLedLabel = uilabel(app.GridLayout);
            app.RedLedLabel.Text = 'Red Led';
            app.GreenLedLabel = uilabel(app.GridLayout);
            app.GreenLedLabel.Text = 'Green Led';
            app.YellowLedLabel = uilabel(app.GridLayout);
            app.YellowLedLabel.Text = 'Yellow Led';
            app.MQTTConnectionPanel = uipanel(app.GUIPanel);
            app.MQTTConnectionPanel.Title = 'MQTT Connection';
            app.GridLayout4 = uigridlayout(app.MQTTConnectionPanel);
            app.ServerUrlEditFieldLabel = uilabel(app.GridLayout4);
            app.ServerUrlEditFieldLabel.Text = 'Server Url';
            app.ServerUrlEditField = uieditfield(app.GridLayout4, 'text');
            app.ServerUrlEditField.Value = 'mqtt://test.mosquitto.org';
            app.ServerPortEditFieldLabel = uilabel(app.GridLayout4);
            app.ServerPortEditFieldLabel.Text = 'Server Port';
            app.ServerPortEditField = uieditfield(app.GridLayout4, 'text');
            app.ServerPortEditField.InputType = 'digits';
            app.ServerPortEditField.Value = '1883';
            app.mqttConnectSwitch = uiswitch(app.GridLayout4, 'slider');
            app.mqttConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.mqttConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @mqttConnectSwitchValueChanged, true);
            app.mqttConnectSwitch.Value = 'Disconnect';
            app.ConnectStatusLabel = uilabel(app.GridLayout4);
            app.ConnectStatusLabel.Text = 'Connect Status';
            app.LidarDataPanel = uipanel(app.GUIPanel);
            app.LidarDataPanel.Title = 'Lidar Data';
            app.GridLayout5 = uigridlayout(app.LidarDataPanel);
            app.FrontLabel = uilabel(app.GridLayout5);
            app.FrontLabel.Text = 'Front:';
            app.BackLabel = uilabel(app.GridLayout5);
            app.BackLabel.Text = 'Back:';
            app.LeftLabel = uilabel(app.GridLayout5);
            app.LeftLabel.Text = 'Left:';
            app.RightLabel = uilabel(app.GridLayout5);
            app.RightLabel.Text = 'Right:';
            app.frontLidarData = uilabel(app.GridLayout5);
            app.frontLidarData.Text = 'Unknown';
            app.backLidarData = uilabel(app.GridLayout5);
            app.backLidarData.Text = 'Unknown';
            app.leftLidarData = uilabel(app.GridLayout5);
            app.leftLidarData.Text = 'Unknown';
            app.rightLidarData = uilabel(app.GridLayout5);
            app.rightLidarData.Text = 'Unknown';
            app.SonarDataPanel = uipanel(app.GUIPanel);
            app.SonarDataPanel.Title = 'Sonar Data';
            app.GridLayout6 = uigridlayout(app.SonarDataPanel);
            app.LeftLabel_2 = uilabel(app.GridLayout6);
            app.LeftLabel_2.Text = 'Left:';
            app.RightLabel_2 = uilabel(app.GridLayout6);
            app.RightLabel_2.Text = 'Right:';
            app.leftSonarData = uilabel(app.GridLayout6);
            app.leftSonarData.Text = 'Unknown';
            app.rightSonarData = uilabel(app.GridLayout6);
            app.rightSonarData.Text = 'Unknown';
            app.EnckderDataPanel = uipanel(app.GUIPanel);
            app.EnckderDataPanel.Title = 'Encoder Data';
            app.GridLayout6_2 = uigridlayout(app.EnckderDataPanel);
            app.LeftEncoderLabel = uilabel(app.GridLayout6_2);
            app.LeftEncoderLabel.Text = 'Left:';
            app.RightEncoderLabel = uilabel(app.GridLayout6_2);
            app.RightEncoderLabel.Text = 'Right:';
            app.leftEncoderData = uilabel(app.GridLayout6_2);
            app.leftEncoderData.Text = 'Unknown';
            app.rightEncoderData = uilabel(app.GridLayout6_2);
            app.rightEncoderData.Text = 'Unknown';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end